classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TRANSMISSIONLINEINPUTIMPEDENCECALCULATORANDPLOTTERLabel  matlab.ui.control.Label
        PLOT_2XinVsZLOADButton     matlab.ui.control.StateButton
        TO_ZLOADEditField          matlab.ui.control.NumericEditField
        TO_ZLOADEditFieldLabel     matlab.ui.control.Label
        jLabel_4                   matlab.ui.control.Label
        TO_XLOADEditField          matlab.ui.control.NumericEditField
        jLabel_3                   matlab.ui.control.Label
        jLabel_2                   matlab.ui.control.Label
        jLabel                     matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        Label_12                   matlab.ui.control.Label
        PLOT_1RinVsZLOADButton     matlab.ui.control.StateButton
        FROM_XLOADEditField        matlab.ui.control.NumericEditField
        FROM_ZLOADEditField        matlab.ui.control.NumericEditField
        FORM_ZLOADLabel            matlab.ui.control.Label
        GENERATINGPLOTFORRANGEOFLOADIMPEDENCELabel  matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        ZinEditField               matlab.ui.control.EditField
        ZinEditFieldLabel          matlab.ui.control.Label
        CALCULATEButton            matlab.ui.control.StateButton
        Label_8                    matlab.ui.control.Label
        ZoEditField                matlab.ui.control.NumericEditField
        ZoLabel                    matlab.ui.control.Label
        betaEditField              matlab.ui.control.NumericEditField
        betaEditFieldLabel         matlab.ui.control.Label
        XoEditField                matlab.ui.control.NumericEditField
        Label_7                    matlab.ui.control.Label
        NpmLabel                   matlab.ui.control.Label
        alpha                      matlab.ui.control.NumericEditField
        Label_5                    matlab.ui.control.Label
        Label_10                   matlab.ui.control.Label
        lengthEditField            matlab.ui.control.NumericEditField
        lengthLabel                matlab.ui.control.Label
        CALCULATEFORPARTICULARLOADLabel  matlab.ui.control.Label
        XLOADEditField             matlab.ui.control.NumericEditField
        ZLOADEditField             matlab.ui.control.NumericEditField
        ZLOADEditFieldLabel        matlab.ui.control.Label
        LINEPARAMETERSLabel        matlab.ui.control.Label
        radsecLabel                matlab.ui.control.Label
        omega                      matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        SETTINGWAVEPARAMETERLabel  matlab.ui.control.Label
        radmLabel                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CALCULATEButton, UIFigure
        function PLOTButtonPushed(app, event)
            b = app.betaEditField.Value; % beta
            %w = app.omega.Value;         % omega
            lamda = 2*pi/b   ;           %lamda
            a = app.alpha.Value;         %alpha
            Rl = app.ZLOADEditField.Value; % load resistance
            Xl = app.XLOADEditField.Value; % load impedance
            Zl = Rl + 1j*Xl;                % total load impedance1
            l_in_lamda = app.lengthEditField.Value; % l_in_lamda
            l = lamda*l_in_lamda;          %real value of lenght in metre
            Ro = app.ZoEditField.Value;    % charactersic resistance
            Xo = app.XoEditField.Value;    % characteristics impedence
            Zo = Ro + 1j*Xo;
            g = a + 1j*b;
            Zin = Zo*((Zl+Zo*tanh(g*l))/(Zo+Zl*tanh(g*l)));
            app.ZinEditField.Value = string(Zin);
            
            
        end

        % Value changed function: PLOT_1RinVsZLOADButton
        function PLOT_1RinVsZLOADButtonValueChanged(app, event)
            b = app.betaEditField.Value; % beta
            %w = app.omega.Value;         % omega
            lamda = 2*pi/b   ;           %lamda
            a = app.alpha.Value;         %alpha
            l_in_lamda = app.lengthEditField.Value; % l_in_lamda
            l = lamda*l_in_lamda;          %real value of lenght in metre
            Ro = app.ZoEditField.Value;    % charactersic resistance
            Xo = app.XoEditField.Value;    % characteristics impedence
            Zo = Ro + 1j*Xo;
            g = a + 1j*b;
            R_start = app.FROM_ZLOADEditField.Value;
            X_start = app.FROM_XLOADEditField.Value;
            R_end = app.TO_ZLOADEditField.Value;
            X_end = app.TO_XLOADEditField.Value;
            x = [R_start:0.1:R_end];
            y = [X_start,0.1:X_end];
            [xx,yy] = meshgrid(x,y);
            Zl = (xx) + 1j*(yy);  % total load impedance1
            zzz = Zo.*((Zl+Zo*tanh(g*l))./(Zo+ tanh(g*l).*Zl));
            zz = real(zzz);
            mesh(xx,yy,zz);
            title('Rin Vs Z(LOAD)');
            xlabel('R(LOAD)');
            ylabel('X(LOAD)');
            zlabel('Rin');
            
            %
        end

        % Value changed function: PLOT_2XinVsZLOADButton
        function PLOT_2XinVsZLOADButtonValueChanged(app, event)
            b = app.betaEditField.Value; % beta
            %w = app.omega.Value;         % omega
            lamda = 2*pi/b   ;           %lamda
            a = app.alpha.Value;         %alpha
            l_in_lamda = app.lengthEditField.Value; % l_in_lamda
            l = lamda*l_in_lamda;          %real value of lenght in metre
            Ro = app.ZoEditField.Value;    % charactersic resistance
            Xo = app.XoEditField.Value;    % characteristics impedence
            Zo = Ro + 1j*Xo;
            g = a + 1j*b;
            R_start = app.FROM_ZLOADEditField.Value;
            X_start = app.FROM_XLOADEditField.Value;
            R_end = app.TO_ZLOADEditField.Value;
            X_end = app.TO_XLOADEditField.Value;
            x = [R_start:0.1:R_end];
            y = [X_start,0.1:X_end];
            [xx,yy] = meshgrid(x,y);
            Zl = (xx) + 1j*(yy);  % total load impedance1
            zzz = Zo.*((Zl+Zo*tanh(g*l))./(Zo+ tanh(g*l).*Zl));
            zz = imag(zzz);
            mesh(xx,yy,zz);
            title('Xin Vs Z(LOAD)');
            xlabel('R(LOAD)');
            ylabel('X(LOAD)');
            zlabel('Xin');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.radmLabel = uilabel(app.UIFigure);
            app.radmLabel.Text = 'rad/m';
            app.SETTINGWAVEPARAMETERLabel = uilabel(app.UIFigure);
            app.SETTINGWAVEPARAMETERLabel.Text = 'SETTING WAVE PARAMETER';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ω';
            app.omega = uieditfield(app.UIFigure, 'numeric');
            app.radsecLabel = uilabel(app.UIFigure);
            app.radsecLabel.Text = 'rad/sec';
            app.LINEPARAMETERSLabel = uilabel(app.UIFigure);
            app.LINEPARAMETERSLabel.Text = 'LINE PARAMETERS';
            app.ZLOADEditFieldLabel = uilabel(app.UIFigure);
            app.ZLOADEditFieldLabel.Text = 'Z(LOAD) = ';
            app.ZLOADEditField = uieditfield(app.UIFigure, 'numeric');
            app.XLOADEditField = uieditfield(app.UIFigure, 'numeric');
            app.CALCULATEFORPARTICULARLOADLabel = uilabel(app.UIFigure);
            app.CALCULATEFORPARTICULARLOADLabel.Text = 'CALCULATE FOR PARTICULAR LOAD';
            app.lengthLabel = uilabel(app.UIFigure);
            app.lengthLabel.Text = 'length = ';
            app.lengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = 'λ';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'α';
            app.alpha = uieditfield(app.UIFigure, 'numeric');
            app.NpmLabel = uilabel(app.UIFigure);
            app.NpmLabel.Text = 'Np/m';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Ω';
            app.XoEditField = uieditfield(app.UIFigure, 'numeric');
            app.betaEditFieldLabel = uilabel(app.UIFigure);
            app.betaEditFieldLabel.Text = 'beta';
            app.betaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZoLabel = uilabel(app.UIFigure);
            app.ZoLabel.Text = ' Zo  =';
            app.ZoEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = 'Ω';
            app.CALCULATEButton = uibutton(app.UIFigure, 'state');
            app.CALCULATEButton.ValueChangedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.ZinEditFieldLabel = uilabel(app.UIFigure);
            app.ZinEditFieldLabel.Text = 'Zin';
            app.ZinEditField = uieditfield(app.UIFigure, 'text');
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = 'Ω';
            app.GENERATINGPLOTFORRANGEOFLOADIMPEDENCELabel = uilabel(app.UIFigure);
            app.GENERATINGPLOTFORRANGEOFLOADIMPEDENCELabel.Text = 'GENERATING PLOT FOR RANGE OF LOAD IMPEDENCE';
            app.FORM_ZLOADLabel = uilabel(app.UIFigure);
            app.FORM_ZLOADLabel.Text = 'FORM _Z(LOAD)  = ';
            app.FROM_ZLOADEditField = uieditfield(app.UIFigure, 'numeric');
            app.FROM_XLOADEditField = uieditfield(app.UIFigure, 'numeric');
            app.PLOT_1RinVsZLOADButton = uibutton(app.UIFigure, 'state');
            app.PLOT_1RinVsZLOADButton.ValueChangedFcn = createCallbackFcn(app, @PLOT_1RinVsZLOADButtonValueChanged, true);
            app.PLOT_1RinVsZLOADButton.Text = 'PLOT_1   Rin Vs Z(LOAD)';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = 'Ω';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = 'Ω';
            app.jLabel = uilabel(app.UIFigure);
            app.jLabel.Text = '+ j';
            app.jLabel_2 = uilabel(app.UIFigure);
            app.jLabel_2.Text = '+ j';
            app.jLabel_3 = uilabel(app.UIFigure);
            app.jLabel_3.Text = '+ j';
            app.TO_XLOADEditField = uieditfield(app.UIFigure, 'numeric');
            app.jLabel_4 = uilabel(app.UIFigure);
            app.jLabel_4.Text = '+ j';
            app.TO_ZLOADEditFieldLabel = uilabel(app.UIFigure);
            app.TO_ZLOADEditFieldLabel.Text = 'TO _Z(LOAD)  = ';
            app.TO_ZLOADEditField = uieditfield(app.UIFigure, 'numeric');
            app.PLOT_2XinVsZLOADButton = uibutton(app.UIFigure, 'state');
            app.PLOT_2XinVsZLOADButton.ValueChangedFcn = createCallbackFcn(app, @PLOT_2XinVsZLOADButtonValueChanged, true);
            app.PLOT_2XinVsZLOADButton.Text = 'PLOT_2   Xin Vs Z(LOAD)';
            app.TRANSMISSIONLINEINPUTIMPEDENCECALCULATORANDPLOTTERLabel = uilabel(app.UIFigure);
            app.TRANSMISSIONLINEINPUTIMPEDENCECALCULATORANDPLOTTERLabel.Text = 'TRANSMISSION LINE INPUT IMPEDENCE CALCULATOR AND PLOTTER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end